classdef metadata_wizard < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        BrainFlowZZZMetadataWizardpage15Panel  matlab.ui.container.Panel
        HomePageLabel                   matlab.ui.control.Label
        BrainFlowZZZMetadataWizardLabel  matlab.ui.control.Label
        NextButton_1                    matlab.ui.control.Button
        SelectDirectoryTextArea         matlab.ui.control.TextArea
        selectdirectoryButton           matlab.ui.control.Button
        BrainFlowZZZMetadataWizardpage25Panel  matlab.ui.container.Panel
        ExperimentInfoLabel             matlab.ui.control.Label
        MicroscopeEditFieldLabel        matlab.ui.control.Label
        ManufacturerEditFieldLabel      matlab.ui.control.Label
        ExperimentDescriptionTextArea_2Label  matlab.ui.control.Label
        EditField_2                     matlab.ui.control.EditField
        ErrorMessageLabel_2             matlab.ui.control.Label
        ExperimenterInfoLabel           matlab.ui.control.Label
        BackButton_2                    matlab.ui.control.Button
        NextButton_2                    matlab.ui.control.Button
        InstitutionEditField_2Label     matlab.ui.control.Label
        ExperimentDescriptionTextArea   matlab.ui.control.TextArea
        ManufacturerEditField           matlab.ui.control.EditField
        MicroscopeEditField             matlab.ui.control.EditField
        InstitutionEditField            matlab.ui.control.EditField
        NameEditFieldLabel              matlab.ui.control.Label
        NameEditField                   matlab.ui.control.EditField
        BrainFlowZZZMetadataWizardpage35Panel  matlab.ui.container.Panel
        SessionIDEditFieldLabel         matlab.ui.control.Label
        IdentifierEditFieldLabel        matlab.ui.control.Label
        StartTimeEditFieldLabel         matlab.ui.control.Label
        KeywordsTextAreaLabel           matlab.ui.control.Label
        SessionDescriptionLabel         matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        ErrorMessageLabel               matlab.ui.control.Label
        SessionInfoLabel                matlab.ui.control.Label
        BackButton_3                    matlab.ui.control.Button
        NextButton_3                    matlab.ui.control.Button
        SessionDescriptionTextArea      matlab.ui.control.TextArea
        KeywordsTextArea                matlab.ui.control.TextArea
        StartTimeEditField              matlab.ui.control.EditField
        IdentifierEditField             matlab.ui.control.EditField
        SessionIDEditField              matlab.ui.control.EditField
        BrainFlowZZZMetadataWizardpage45Panel  matlab.ui.container.Panel
        GridSpacingUnitEditFieldLabel   matlab.ui.control.Label
        appDataTextArea                 matlab.ui.control.TextArea
        appDataTextAreaLabel            matlab.ui.control.Label
        NoteLabel                       matlab.ui.control.Label
        LocationEditFieldLabel          matlab.ui.control.Label
        StartingTimeRateEditFieldLabel  matlab.ui.control.Label
        StartingTimeEditFieldLabel      matlab.ui.control.Label
        EditField_4                     matlab.ui.control.EditField
        TwoPhotonSeriesInfoLabel        matlab.ui.control.Label
        ChannelInfoLabel                matlab.ui.control.Label
        BackButton_4                    matlab.ui.control.Button
        NextButton_4                    matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        ChanATab                        matlab.ui.container.Tab
        IndicatorEditFieldLabel         matlab.ui.control.Label
        IndicatorEditField_1            matlab.ui.control.EditField
        ExcitationLambdaEditFieldLabel  matlab.ui.control.Label
        ExcitationLambdaEditField_1     matlab.ui.control.EditField
        ChannelNameEditFieldLabel       matlab.ui.control.Label
        EmissionLambdaEditField_2Label  matlab.ui.control.Label
        EmissionLambdaEditField_1       matlab.ui.control.EditField
        ChannelNameEditField_1          matlab.ui.control.EditField
        ChanBTab                        matlab.ui.container.Tab
        IndicatorEditField_2Label       matlab.ui.control.Label
        IndicatorEditField_2            matlab.ui.control.EditField
        EmissionLambdaEditField_3Label  matlab.ui.control.Label
        ExcitationLambdaEditField_2Label  matlab.ui.control.Label
        ExcitationLambdaEditField_2     matlab.ui.control.EditField
        ChannelNameEditField_2Label     matlab.ui.control.Label
        EmissionLambdaEditField_2       matlab.ui.control.EditField
        ChannelNameEditField_2          matlab.ui.control.EditField
        ChanCTab                        matlab.ui.container.Tab
        IndicatorEditField_3Label       matlab.ui.control.Label
        IndicatorEditField_3            matlab.ui.control.EditField
        EmissionLambdaEditField_4Label  matlab.ui.control.Label
        ExcitationLambdaEditField_3Label  matlab.ui.control.Label
        ExcitationLambdaEditField_3     matlab.ui.control.EditField
        ChannelNameEditField_3Label     matlab.ui.control.Label
        EmissionLambdaEditField_3       matlab.ui.control.EditField
        ChannelNameEditField_3          matlab.ui.control.EditField
        GridSpacingUnitEditField        matlab.ui.control.EditField
        LocationEditField               matlab.ui.control.EditField
        StartingTimeRateEditField       matlab.ui.control.EditField
        StartingTimeEditField           matlab.ui.control.EditField
        BrainFlowZZZMetadataWizardpage55Panel  matlab.ui.container.Panel
        subjectinfoautofilledLabel      matlab.ui.control.Label
        SubjectIDEditFieldLabel         matlab.ui.control.Label
        SubjectSpeciesDropDownLabel     matlab.ui.control.Label
        SubjectDOBDatePickerLabel       matlab.ui.control.Label
        SubjectSexDropDownLabel         matlab.ui.control.Label
        SubjectStrainEditFieldLabel     matlab.ui.control.Label
        currentDirTextArea              matlab.ui.control.TextArea
        currentDirTextAreaLabel         matlab.ui.control.Label
        SubjectInfoLabel                matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        BackButton_5                    matlab.ui.control.Button
        SubmitButton                    matlab.ui.control.Button
        SubjectStrainEditField          matlab.ui.control.EditField
        SubjectSexDropDown              matlab.ui.control.DropDown
        SubjectDOBDatePicker            matlab.ui.control.DatePicker
        SubjectSpeciesDropDown          matlab.ui.control.DropDown
        SubjectIDEditField              matlab.ui.control.EditField
    end

    
    properties (Access = private)
        % Get the full path of the metadata wizard
        currentDir = fileparts(mfilename('fullpath'));
        defaultUigetdir
        appDataFile
        experimentInfo = struct;
        appData = struct;
        channelData = struct;
        % must use double quotes below
        channels = ["chanA", "chanB", "chanC"];
        channelFields = ["channelName", "excitationLambda", "emissionLambda", "indicator"];
    end

    methods (Access = private)
        
        function [] = map_channelData(app)
            % initialize channel - component map
            app.channelData = struct( ...
                'chanA', struct, ...
                'chanB', struct, ...
                'chanC', struct ...
                );

            % chanA info
            app.channelData.chanA.channelName = app.ChannelNameEditField_1;
            app.channelData.chanA.excitationLambda = app.EmissionLambdaEditField_1;
            app.channelData.chanA.emissionLambda = app.ExcitationLambdaEditField_1;
            app.channelData.chanA.indicator = app.IndicatorEditField_1;

            % chanB info
            app.channelData.chanB.channelName = app.ChannelNameEditField_2;
            app.channelData.chanB.excitationLambda = app.EmissionLambdaEditField_2;
            app.channelData.chanB.emissionLambda = app.ExcitationLambdaEditField_2;
            app.channelData.chanB.indicator = app.IndicatorEditField_2;

            % chanC info
            app.channelData.chanC.channelName = app.ChannelNameEditField_3;
            app.channelData.chanC.excitationLambda = app.EmissionLambdaEditField_3;
            app.channelData.chanC.emissionLambda = app.ExcitationLambdaEditField_3;
            app.channelData.chanC.indicator = app.IndicatorEditField_3;

        end

        function [] = write_appData(app, subjectID)
            % experiment info
            app.appData.experimenter = app.NameEditField.Value;
            app.appData.institution = app.InstitutionEditField.Value;
            app.appData.microscope = app.MicroscopeEditField.Value;
            app.appData.manufacturer = app.ManufacturerEditField.Value;
            app.appData.defaultUigetdir = strrep(app.defaultUigetdir, '\', '\\');

            % subject info
            app.appData.subjectInfo.(subjectID) = struct;
            app.appData.subjectInfo.(subjectID).subjectStrain = app.SubjectStrainEditField.Value;
            app.appData.subjectInfo.(subjectID).subjectSex = app.SubjectSexDropDown.Value;
            app.appData.subjectInfo.(subjectID).subjectDOB = app.SubjectDOBDatePicker.Value;
            app.appData.subjectInfo.(subjectID).subjectSpecies = app.SubjectSpeciesDropDown.Value;

            jsonString = jsonencode(app.appData);
            fileID = fopen(app.appDataFile, 'w');           
            fprintf(fileID, jsonString);
            fclose(fileID);
        end

        function [] = load_appData(app)
            % to load at Next button on page 1
            app.appDataFile = char(fullfile(app.currentDir, "app_data.json"));
            app.currentDirTextArea.Value = app.appDataFile;
            app.defaultUigetdir = app.currentDir;
            if isfile(app.appDataFile)
                if isfield(app.appData, 'defaultUigetdir')
                    app.defaultUigetdir = app.appData.defaultUigetdir;
                end
                app.appData = jsondecode(fileread(app.appDataFile));
                app.NameEditField.Value = app.appData.experimenter;
                app.InstitutionEditField.Value = app.appData.institution;
                app.MicroscopeEditField.Value = app.appData.microscope;
                app.ManufacturerEditField.Value = app.appData.manufacturer;
            end
        end

        function [] = write_metadata(app)
            metadata = struct;

            % experiment section
            metadata.experimenter = app.NameEditField.Value;
            metadata.institution = app.InstitutionEditField.Value;
            metadata.microscope = app.MicroscopeEditField.Value;
            metadata.manufacturer = app.ManufacturerEditField.Value;
            metadata.experimentDescription = char(app.ExperimentDescriptionTextArea.Value);

            % session section
            metadata.sessionID = app.SessionIDEditField.Value;
            metadata.identifier = app.IdentifierEditField.Value;
            metadata.startTime = app.StartTimeEditField.Value;
            metadata.keywords = char(app.KeywordsTextArea.Value);
            metadata.sessionDescription = char(app.SessionDescriptionTextArea.Value);

            % two-photon section
            metadata.startingTime = app.StartingTimeEditField.Value;
            metadata.startingTimeRate = app.StartingTimeRateEditField.Value;
            metadata.location = app.LocationEditField.Value;
            metadata.gridSpacingUnit = app.GridSpacingUnitEditField.Value;

            % channel section
            metadata.channelData = struct;
            for channel = app.channels
                metadata.channelData.(channel) = struct;
                for channelField = app.channelFields
                    metadata.channelData.(channel).(channelField) = app.channelData.(channel).(channelField).Value;
                end
            end

            % subject section
            metadata.subjectID = app.SubjectIDEditField.Value;
            metadata.subjectSpecies = char(app.SubjectSpeciesDropDown.Value);
            metadata.subjectDOB = app.SubjectDOBDatePicker.Value;
            metadata.subjectSex = app.SubjectSexDropDown.Value;
            metadata.subjectStrain = app.SubjectStrainEditField.Value;

            % Step 2: Convert the MATLAB structure to a JSON formatted string
            jsonString = jsonencode(metadata);
            % Step 3: Open a file for writing
            filename = fullfile(string(app.SelectDirectoryTextArea.Value), "metadata_" + string(app.SessionIDEditField.Value) + ".json");
            if isfile(filename)
                fileattrib(filename, '+w', 'a'); % Remove write permission for all users
            end
            %app.Label.Text = app.SelectDirectoryTextArea.Value;
            fileID = fopen(filename, 'w');
                            
            % Step 4: Write the JSON string to the file
            fprintf(fileID, jsonString);
            
            % Step 5: Close the file
            fclose(fileID);
            fileattrib(filename, '-w', 'a'); % Remove write permission for all users
        end
        
        function [] = reset_fields(app)
            app.SelectDirectoryTextArea.Value = '';

            % experiment section
            app.NameEditField.Value = '';
            app.InstitutionEditField.Value = '';
            app.MicroscopeEditField.Value = '';
            app.ManufacturerEditField.Value = '';
            app.ExperimentDescriptionTextArea.Value = '';

            % session section
            app.SessionIDEditField.Value = '';
            app.IdentifierEditField.Value = '';
            app.StartTimeEditField.Value = '';
            app.KeywordsTextArea.Value = '';
            app.SessionDescriptionTextArea.Value = '';

            % two-photon section
            app.StartingTimeEditField.Value = '0';
            app.StartingTimeRateEditField.Value = '';
            app.LocationEditField.Value = '';
            app.GridSpacingUnitEditField.Value = 'microns';

            % channel section
            app.TabGroup.SelectedTab = app.ChanATab;
            for channel = app.channels
                for channelField = app.channelFields
                    app.channelData.(channel).(channelField).Value = '';
                end
            end

            % subject section
            app.SubjectIDEditField.Value = '';
            app.SubjectSpeciesDropDown.Value = 'please select';
            app.SubjectDOBDatePicker.Value = NaT;
            app.SubjectSexDropDown.Value = 'please select';
            app.SubjectStrainEditField.Value = '';
            app.subjectinfoautofilledLabel.Visible = false;
        end
        
        function [] = load_metadata(app, folderName)
            jsonFile = fullfile(string(app.SelectDirectoryTextArea.Value), "metadata_" + string(folderName) + ".json");
            fileContent = fileread(jsonFile);

            % Decode the JSON content
            metadata = jsondecode(fileContent);

            % experiment section
            app.NameEditField.Value = metadata.experimenter;
            app.InstitutionEditField.Value = metadata.institution;
            app.MicroscopeEditField.Value = metadata.microscope;
            app.ManufacturerEditField.Value = metadata.manufacturer;
            app.ExperimentDescriptionTextArea.Value = metadata.experimentDescription;

            % session section
            app.SessionIDEditField.Value = metadata.sessionID;
            app.IdentifierEditField.Value = metadata.identifier;
            app.StartTimeEditField.Value = metadata.startTime;
            app.KeywordsTextArea.Value = metadata.keywords;
            app.SessionDescriptionTextArea.Value = metadata.sessionDescription;

            % two-photon section
            app.StartingTimeEditField.Value = metadata.startingTime;
            app.StartingTimeRateEditField.Value = metadata.startingTimeRate;
            app.LocationEditField.Value = metadata.location;
            app.GridSpacingUnitEditField.Value = metadata.gridSpacingUnit;

            % channel info
            map_channelData(app)
            for channel = app.channels
                for channelField = app.channelFields
                    app.channelData.(channel).(channelField).Value = metadata.channelData.(channel).(channelField);
                end
            end

            % subject section
            app.SubjectIDEditField.Value = metadata.subjectID;
            app.SubjectSpeciesDropDown.Value = metadata.subjectSpecies;
            app.SubjectDOBDatePicker.Value = datetime(metadata.subjectDOB);
            app.SubjectSexDropDown.Value = metadata.subjectSex;
            app.SubjectStrainEditField.Value = metadata.subjectStrain;
        end
        
        function expData = load_experiment_xml(app)
            xmlFile = fullfile(char(app.SelectDirectoryTextArea.Value), 'Experiment.xml');
            s = xml2struct(xmlFile); % use function from file exchange 
            expData = struct;
            try
                expData.date = char(s.ThorImageExperiment.Date.Attributes.date);
            catch
                expData.date = '';
            end

            try
                expData.nChannels = numel(s.ThorImageExperiment.Wavelengths.Wavelength);
            catch
                expData.nChannels = [];
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BackButton_5
        function BackButton_5Pushed(app, event)
            app.BrainFlowZZZMetadataWizardpage45Panel.Visible = 'on';
            app.BrainFlowZZZMetadataWizardpage55Panel.Visible = 'off';
        end

        % Button pushed function: NextButton_3
        function NextButton_3Pushed(app, event)
            startTime = app.StartTimeEditField.Value;
            error = false;

            if isempty(app.SessionIDEditField.Value)
                error = true;
                uialert(app.UIFigure, 'Please enter the session ID.', 'Missing Field', 'Icon', 'error');

            elseif isempty(app.IdentifierEditField.Value)
                error = true;
                uialert(app.UIFigure, 'Please enter the indentifier.', 'Missing Field', 'Icon', 'error');

            elseif isempty(startTime)
                error = true;
                uialert(app.UIFigure, 'Please enter the start time.', 'Missing Field', 'Icon', 'error');

            elseif (strtrim(app.KeywordsTextArea.Value) == "")
                error = true;
                uialert(app.UIFigure, 'Please enter the keywords.', 'Missing Field', 'Icon', 'error');

            elseif (strtrim(app.SessionDescriptionTextArea.Value) == "")
                error = true;
                uialert(app.UIFigure, 'Please enter the session description.', 'Missing Field', 'Icon', 'error');                
            end

            try
               datetime(startTime,'Format','yyyy-MM-dd HH:mm:ss');
            catch
               error = true;
            end
            
            if ~error
                app.BrainFlowZZZMetadataWizardpage35Panel.Visible = 'off';
                app.BrainFlowZZZMetadataWizardpage45Panel.Visible = 'on'; 
            end
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            error = false;
            if isempty(app.SubjectIDEditField.Value)
                error = true;
                uialert(app.UIFigure, 'Please enter subject ID.', 'Missing Field', 'Icon', 'error');

            elseif strcmp(app.SubjectSpeciesDropDown.Value, 'please select')
                error = true;
                uialert(app.UIFigure, 'Please select subject species.', 'Missing Field', 'Icon', 'error');
            
            elseif isempty(app.SubjectDOBDatePicker.Value)
                error = true;
                uialert(app.UIFigure, 'Please enter subject DOB.', 'Missing Field', 'Icon', 'error');
            
            elseif strcmp(app.SubjectSexDropDown.Value, 'please select')
                error = true;
                uialert(app.UIFigure, 'Please select subject sex.', 'Missing Field', 'Icon', 'error');

            elseif isempty(app.SubjectStrainEditField.Value)
                error = true;
                uialert(app.UIFigure, 'Please enter subject DOB.', 'Missing Field', 'Icon', 'error');
            end

            if ~error
                write_metadata(app)
                message = strcat("metadata written to ", string(app.SelectDirectoryTextArea.Value), "/", "metadata_", string(app.SessionIDEditField.Value), ".json");
                title = 'Metadata Saved';
                icon = 'success';
                
                % replace hyphens with undersocres in the subjectID because
                % hypens are not allowed in matlab identifiers
                app.SubjectIDEditField.Value = replace(app.SubjectIDEditField.Value, '-', '_');
                write_appData(app, app.SubjectIDEditField.Value)
                uialert(app.UIFigure, message, title, 'Icon', icon, 'CloseFcn', @reset_app);
            end
            
            function reset_app(src, event)
                reset_fields(app)
                app.BrainFlowZZZMetadataWizardpage15Panel.Visible = 'on';
                app.BrainFlowZZZMetadataWizardpage55Panel.Visible = 'off';
            end
        end

        % Callback function: SelectDirectoryTextArea, selectdirectoryButton
        function selectdirectoryButtonPushed(app, event)
            load_appData(app)
            f = figure('Position', [-100 -100 0 0]); % create a dummy figure so that uigetfile doesn't minimize our GUI
            selectedDir = uigetdir(app.defaultUigetdir);
            [parentFolder, ~, ~] = fileparts(string(selectedDir));
            app.defaultUigetdir = parentFolder;
            % Check if a directory was selected (uigetdir returns 0 if the user cancels the dialog)
            delete(f);
            if selectedDir ~= 0
                % Store the selected directory path in the Edit Field
                app.SelectDirectoryTextArea.Value = selectedDir;
            end
        end

        % Button pushed function: BackButton_3
        function BackButton_3Pushed(app, event)
            app.BrainFlowZZZMetadataWizardpage25Panel.Visible = 'on';
            app.BrainFlowZZZMetadataWizardpage35Panel.Visible = 'off';
        end

        % Button pushed function: NextButton_1
        function NextButton_1Pushed(app, event)
            if (app.SelectDirectoryTextArea.Value == "")
                uialert(app.UIFigure, 'Please select a directory.', 'Missing Field', 'Icon', 'error');
            return;
            end

            [~, folderName, ~] = fileparts(char(app.SelectDirectoryTextArea.Value));
            jsonFile = fullfile(char(app.SelectDirectoryTextArea.Value), 'metadata_' + string(folderName) + '.json');
            if isfile(jsonFile)
                selection = uiconfirm(app.UIFigure, ...
                    'Metadata already exists in this folder. Do you wish to update it?', ...
                    'Confirmation', ...
                    'Options', {'Yes', 'No'}, ...
                    'DefaultOption', 2, ...
                    'CancelOption', 2);
                if strcmp(selection, 'Yes')
                    load_metadata(app, folderName)
                else
                    app.SelectDirectoryTextArea.Value = '';
                    return
                end
            
            else
                xmlFile = fullfile(char(app.SelectDirectoryTextArea.Value), 'Experiment.xml');
                if isfile(xmlFile)
                    xmlData = load_experiment_xml(app);
                    try
                        date = datetime(xmlData.date, 'InputFormat', 'MM/dd/yyyy HH:mm:ss');
                    catch 
                        date = datetime(xmlData.date);
                    end
                    app.IdentifierEditField.Value = string(date, 'yyyy-MM-dd HH:mm:ss'); % use the date as the Identifier
                    app.StartTimeEditField.Value = string(date, 'yyyy-MM-dd HH:mm:ss');
                    app.SessionIDEditField.Value = folderName;
                %else
                %    app.IdentifierEditField.Value = '';
                end

            end
            % load_appData(app)
            app.BrainFlowZZZMetadataWizardpage15Panel.Visible = 'off';
            app.BrainFlowZZZMetadataWizardpage25Panel.Visible = 'on';
        end

        % Value changed function: StartTimeEditField
        function StartTimeEditFieldValueChanged(app, event)
            value = app.StartTimeEditField.Value;
            try
               datetime(value,'Format','yyyy-MM-dd HH:mm:ss');
               app.ErrorMessageLabel.Text = '';
            catch
               app.ErrorMessageLabel.Text = 'Invalid Datetime.';
            end
        end

        % Value changed function: SubjectIDEditField
        function SubjectIDEditFieldValueChanged(app, event)
            subjectID = app.SubjectIDEditField.Value;
            subjectID = replace(subjectID, '-', '_');
            if isfield(app.appData, 'subjectInfo')
                if isfield(app.appData.subjectInfo, subjectID)
                    app.subjectinfoautofilledLabel.Visible = true;
                    app.SubjectStrainEditField.Value = app.appData.subjectInfo.(subjectID).subjectStrain;
                    app.SubjectSexDropDown.Value = app.appData.subjectInfo.(subjectID).subjectSex;
                    app.SubjectDOBDatePicker.Value = datetime(app.appData.subjectInfo.(subjectID).subjectDOB, 'InputFormat', 'yyyy-MM-dd');
                    app.SubjectSpeciesDropDown.Value = app.appData.subjectInfo.(subjectID).subjectSpecies;
            
                end  
            end

        end

        % Button pushed function: NextButton_4
        function NextButton_4Pushed(app, event)
            error = false;
            
            if isempty(app.StartingTimeEditField.Value)
                error = true;
                uialert(app.UIFigure, 'Please enter the starting time.', 'Missing Field', 'Icon', 'error');

            elseif isempty(app.StartingTimeRateEditField.Value)
                error = true;
                uialert(app.UIFigure, 'Please enter the starting time rate.', 'Missing Field', 'Icon', 'error');

            elseif isempty(app.LocationEditField.Value)
                error = true;
                uialert(app.UIFigure, 'Please enter the location.', 'Missing Field', 'Icon', 'error');

            elseif isempty(app.GridSpacingUnitEditField.Value)
                error = true;
                uialert(app.UIFigure, 'Please enter the location.', 'Missing Field', 'Icon', 'error');
            
            end

            % TODO: check channel info input
            map_channelData(app);
            channelsPresent = 0;
            for channel = app.channels
                tabFilled = true(1, length(app.channelFields));
                for i = 1:length(app.channelFields)
                    tabFilled(i) = ~isempty(app.channelData.(channel).(app.channelFields(i)).Value);
                end

                if all(tabFilled)
                    channelsPresent = channelsPresent + 1;
                elseif any(tabFilled)
                    channelsPresent = channelsPresent + 1;
                    error = true;
                    uialert(app.UIFigure, "Please complete the tab for " + channel + " or clear all the fields in it.", 'Incomplete Tab', 'Icon', 'error');
                end
            end

            if channelsPresent == 0
                error = true;
                uialert(app.UIFigure, 'Please provide the channel info using the tabs.', 'Missing Field', 'Icon', 'error');
            end

            % debug
            app.appDataTextArea.Value = string(channelsPresent);
            
            if ~error
                app.BrainFlowZZZMetadataWizardpage55Panel.Visible = 'on';
                app.BrainFlowZZZMetadataWizardpage45Panel.Visible = 'off';
            end
        end

        % Button pushed function: BackButton_4
        function BackButton_4Pushed(app, event)
            app.BrainFlowZZZMetadataWizardpage35Panel.Visible = 'on';
            app.BrainFlowZZZMetadataWizardpage45Panel.Visible = 'off';
        end

        % Button pushed function: NextButton_2
        function NextButton_2Pushed(app, event)
            error = false;
            if isempty(app.NameEditField.Value)
                error = true;
                uialert(app.UIFigure, "Please enter the experimenter's name.", 'Missing Field', 'Icon', 'error');

            elseif isempty(regexp(app.NameEditField.Value, '^\s*\S+\s*,\s*\S+\s*$', 'once'))
                error = true;
                uialert(app.UIFigure, "The experimenter's name needs to be in LastName, FirstName format.", 'Incorrect Format', 'Icon', 'error');

            elseif isempty(app.InstitutionEditField.Value)
                error = true;
                uialert(app.UIFigure, 'Please enter institution name.', 'Missing Field', 'Icon', 'error');
            
            elseif isempty(app.MicroscopeEditField.Value)
                error = true;
                uialert(app.UIFigure, 'Please enter microscope model.', 'Missing Field', 'Icon', 'error');
            
            elseif isempty(app.ManufacturerEditField.Value)
                error = true;
                uialert(app.UIFigure, 'Please enter manufacturer.', 'Missing Field', 'Icon', 'error');
            
            elseif (strtrim(app.ExperimentDescriptionTextArea.Value) == "")
                error = true;
                uialert(app.UIFigure, 'Please enter experiment description.', 'Missing Field', 'Icon', 'error');

            end

            if ~error
                app.BrainFlowZZZMetadataWizardpage35Panel.Visible = 'on';
                app.BrainFlowZZZMetadataWizardpage25Panel.Visible = 'off';
            end
        end

        % Button pushed function: BackButton_2
        function BackButton_2Pushed(app, event)
            app.BrainFlowZZZMetadataWizardpage15Panel.Visible = 'on';
            app.BrainFlowZZZMetadataWizardpage25Panel.Visible = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BrainFlowZZZMetadataWizardpage55Panel = uipanel(app.UIFigure);
            app.BrainFlowZZZMetadataWizardpage55Panel.Title = 'BrainFlowZZZ Metadata Wizard (page 5/5)';
            app.SubjectIDEditField = uieditfield(app.BrainFlowZZZMetadataWizardpage55Panel, 'text');
            app.SubjectIDEditField.ValueChangedFcn = createCallbackFcn(app, @SubjectIDEditFieldValueChanged, true);
            app.SubjectIDEditField.Tooltip = {'must start with a letter, and can contain letters, digits, and underscores.'};
            app.SubjectSpeciesDropDown = uidropdown(app.BrainFlowZZZMetadataWizardpage55Panel);
            app.SubjectSpeciesDropDown.Items = {'please select', 'Mus musculus', 'Rattus', 'Homo sapien'};
            app.SubjectSpeciesDropDown.Tooltip = {'mouse, rat, or human'};
            app.SubjectSpeciesDropDown.Value = 'please select';
            app.SubjectDOBDatePicker = uidatepicker(app.BrainFlowZZZMetadataWizardpage55Panel);
            app.SubjectDOBDatePicker.Limits = [datetime([2010 1 1]) datetime([2023 4 7])];
            app.SubjectDOBDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.SubjectSexDropDown = uidropdown(app.BrainFlowZZZMetadataWizardpage55Panel);
            app.SubjectSexDropDown.Items = {'please select', 'M', 'F', 'U', 'O'};
            app.SubjectSexDropDown.Tooltip = {'male, female, unknown, or other.'};
            app.SubjectSexDropDown.Placeholder = 'please select';
            app.SubjectSexDropDown.Value = 'please select';
            app.SubjectStrainEditField = uieditfield(app.BrainFlowZZZMetadataWizardpage55Panel, 'text');
            app.SubmitButton = uibutton(app.BrainFlowZZZMetadataWizardpage55Panel, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            app.BackButton_5 = uibutton(app.BrainFlowZZZMetadataWizardpage55Panel, 'push');
            app.BackButton_5.ButtonPushedFcn = createCallbackFcn(app, @BackButton_5Pushed, true);
            app.BackButton_5.Text = 'Back';
            app.Label = uilabel(app.BrainFlowZZZMetadataWizardpage55Panel);
            app.Label.Text = '';
            app.SubjectInfoLabel = uilabel(app.BrainFlowZZZMetadataWizardpage55Panel);
            app.SubjectInfoLabel.Text = 'Subject Info';
            app.currentDirTextAreaLabel = uilabel(app.BrainFlowZZZMetadataWizardpage55Panel);
            app.currentDirTextAreaLabel.Enable = 'off';
            app.currentDirTextAreaLabel.Visible = 'off';
            app.currentDirTextAreaLabel.Text = 'currentDir';
            app.currentDirTextArea = uitextarea(app.BrainFlowZZZMetadataWizardpage55Panel);
            app.currentDirTextArea.Editable = 'off';
            app.currentDirTextArea.Enable = 'off';
            app.currentDirTextArea.Visible = 'off';
            app.SubjectStrainEditFieldLabel = uilabel(app.BrainFlowZZZMetadataWizardpage55Panel);
            app.SubjectStrainEditFieldLabel.Text = 'Subject Strain';
            app.SubjectSexDropDownLabel = uilabel(app.BrainFlowZZZMetadataWizardpage55Panel);
            app.SubjectSexDropDownLabel.Text = 'Subject Sex';
            app.SubjectDOBDatePickerLabel = uilabel(app.BrainFlowZZZMetadataWizardpage55Panel);
            app.SubjectDOBDatePickerLabel.Text = 'Subject DOB';
            app.SubjectSpeciesDropDownLabel = uilabel(app.BrainFlowZZZMetadataWizardpage55Panel);
            app.SubjectSpeciesDropDownLabel.Text = 'Subject Species';
            app.SubjectIDEditFieldLabel = uilabel(app.BrainFlowZZZMetadataWizardpage55Panel);
            app.SubjectIDEditFieldLabel.Text = 'Subject ID';
            app.subjectinfoautofilledLabel = uilabel(app.BrainFlowZZZMetadataWizardpage55Panel);
            app.subjectinfoautofilledLabel.Visible = 'off';
            app.subjectinfoautofilledLabel.Text = 'subject info auto filled!';
            app.BrainFlowZZZMetadataWizardpage45Panel = uipanel(app.UIFigure);
            app.BrainFlowZZZMetadataWizardpage45Panel.Title = 'BrainFlowZZZ Metadata Wizard (page 4/5)';
            app.StartingTimeEditField = uieditfield(app.BrainFlowZZZMetadataWizardpage45Panel, 'text');
            app.StartingTimeEditField.Tooltip = {'Timestamp of the first sample in seconds.'};
            app.StartingTimeEditField.Value = '0';
            app.StartingTimeRateEditField = uieditfield(app.BrainFlowZZZMetadataWizardpage45Panel, 'text');
            app.StartingTimeRateEditField.Tooltip = {'When timestamps are uniformly spaced, the timestamp of the first sample can be specified and all subsequent ones calculated from the sampling rate attribute. '};
            app.StartingTimeRateEditField.Placeholder = 'Sampling rate in Hz. ';
            app.LocationEditField = uieditfield(app.BrainFlowZZZMetadataWizardpage45Panel, 'text');
            app.LocationEditField.Tooltip = {'Location of the imaging plane. Specify the area, layer, comments on estimation of area/layer, stereotaxic coordinates if in vivo, etc. Use standard atlas names for anatomical regions when possible. '};
            app.LocationEditField.Placeholder = 'eg., location in the brain';
            app.GridSpacingUnitEditField = uieditfield(app.BrainFlowZZZMetadataWizardpage45Panel, 'text');
            app.GridSpacingUnitEditField.Tooltip = {'Measurement units for grid_spacing.'};
            app.GridSpacingUnitEditField.Value = 'microns';
            app.TabGroup = uitabgroup(app.BrainFlowZZZMetadataWizardpage45Panel);
            app.ChanATab = uitab(app.TabGroup);
            app.ChanATab.Title = 'Chan A';
            app.ChannelNameEditField_1 = uieditfield(app.ChanATab, 'text');
            app.ChannelNameEditField_1.Placeholder = 'eg., red, blue, etc.';
            app.EmissionLambdaEditField_1 = uieditfield(app.ChanATab, 'text');
            app.EmissionLambdaEditField_1.InputType = 'digits';
            app.EmissionLambdaEditField_1.Placeholder = 'Emission wavelen in nm.';
            app.EmissionLambdaEditField_2Label = uilabel(app.ChanATab);
            app.EmissionLambdaEditField_2Label.Text = 'Emission Lambda';
            app.ChannelNameEditFieldLabel = uilabel(app.ChanATab);
            app.ChannelNameEditFieldLabel.Text = 'Channel Name';
            app.ExcitationLambdaEditField_1 = uieditfield(app.ChanATab, 'text');
            app.ExcitationLambdaEditField_1.InputType = 'digits';
            app.ExcitationLambdaEditField_1.Placeholder = 'Excitation wavelen in nm. ';
            app.ExcitationLambdaEditFieldLabel = uilabel(app.ChanATab);
            app.ExcitationLambdaEditFieldLabel.Text = 'Excitation Lambda';
            app.IndicatorEditField_1 = uieditfield(app.ChanATab, 'text');
            app.IndicatorEditField_1.Placeholder = 'eg., ''GFP''';
            app.IndicatorEditFieldLabel = uilabel(app.ChanATab);
            app.IndicatorEditFieldLabel.Text = 'Indicator';
            app.ChanBTab = uitab(app.TabGroup);
            app.ChanBTab.Title = 'Chan B';
            app.ChannelNameEditField_2 = uieditfield(app.ChanBTab, 'text');
            app.ChannelNameEditField_2.Placeholder = 'eg., red, blue, etc.';
            app.EmissionLambdaEditField_2 = uieditfield(app.ChanBTab, 'text');
            app.EmissionLambdaEditField_2.InputType = 'digits';
            app.EmissionLambdaEditField_2.Placeholder = 'Emission wavelen in nm.';
            app.ChannelNameEditField_2Label = uilabel(app.ChanBTab);
            app.ChannelNameEditField_2Label.Text = 'Channel Name';
            app.ExcitationLambdaEditField_2 = uieditfield(app.ChanBTab, 'text');
            app.ExcitationLambdaEditField_2.InputType = 'digits';
            app.ExcitationLambdaEditField_2.Placeholder = 'Excitation wavelen in nm. ';
            app.ExcitationLambdaEditField_2Label = uilabel(app.ChanBTab);
            app.ExcitationLambdaEditField_2Label.Text = 'Excitation Lambda';
            app.EmissionLambdaEditField_3Label = uilabel(app.ChanBTab);
            app.EmissionLambdaEditField_3Label.Text = 'Emission Lambda';
            app.IndicatorEditField_2 = uieditfield(app.ChanBTab, 'text');
            app.IndicatorEditField_2.Placeholder = 'eg., ''GFP''';
            app.IndicatorEditField_2Label = uilabel(app.ChanBTab);
            app.IndicatorEditField_2Label.Text = 'Indicator';
            app.ChanCTab = uitab(app.TabGroup);
            app.ChanCTab.Title = 'Chan C';
            app.ChannelNameEditField_3 = uieditfield(app.ChanCTab, 'text');
            app.ChannelNameEditField_3.Placeholder = 'eg., red, blue, etc.';
            app.EmissionLambdaEditField_3 = uieditfield(app.ChanCTab, 'text');
            app.EmissionLambdaEditField_3.InputType = 'digits';
            app.EmissionLambdaEditField_3.Placeholder = 'Emission wavelen in nm.';
            app.ChannelNameEditField_3Label = uilabel(app.ChanCTab);
            app.ChannelNameEditField_3Label.Text = 'Channel Name';
            app.ExcitationLambdaEditField_3 = uieditfield(app.ChanCTab, 'text');
            app.ExcitationLambdaEditField_3.InputType = 'digits';
            app.ExcitationLambdaEditField_3.Placeholder = 'Excitation wavelen in nm. ';
            app.ExcitationLambdaEditField_3Label = uilabel(app.ChanCTab);
            app.ExcitationLambdaEditField_3Label.Text = 'Excitation Lambda';
            app.EmissionLambdaEditField_4Label = uilabel(app.ChanCTab);
            app.EmissionLambdaEditField_4Label.Text = 'Emission Lambda';
            app.IndicatorEditField_3 = uieditfield(app.ChanCTab, 'text');
            app.IndicatorEditField_3.Placeholder = 'eg., ''GFP''';
            app.IndicatorEditField_3Label = uilabel(app.ChanCTab);
            app.IndicatorEditField_3Label.Text = 'Indicator';
            app.NextButton_4 = uibutton(app.BrainFlowZZZMetadataWizardpage45Panel, 'push');
            app.NextButton_4.ButtonPushedFcn = createCallbackFcn(app, @NextButton_4Pushed, true);
            app.NextButton_4.Text = 'Next';
            app.BackButton_4 = uibutton(app.BrainFlowZZZMetadataWizardpage45Panel, 'push');
            app.BackButton_4.ButtonPushedFcn = createCallbackFcn(app, @BackButton_4Pushed, true);
            app.BackButton_4.Text = 'Back';
            app.ChannelInfoLabel = uilabel(app.BrainFlowZZZMetadataWizardpage45Panel);
            app.ChannelInfoLabel.Text = 'Channel Info';
            app.TwoPhotonSeriesInfoLabel = uilabel(app.BrainFlowZZZMetadataWizardpage45Panel);
            app.TwoPhotonSeriesInfoLabel.Text = 'Two-Photon Series Info';
            app.EditField_4 = uieditfield(app.BrainFlowZZZMetadataWizardpage45Panel, 'text');
            app.EditField_4.Editable = 'off';
            app.EditField_4.Enable = 'off';
            app.EditField_4.Visible = 'off';
            app.StartingTimeEditFieldLabel = uilabel(app.BrainFlowZZZMetadataWizardpage45Panel);
            app.StartingTimeEditFieldLabel.Text = 'Starting Time';
            app.StartingTimeRateEditFieldLabel = uilabel(app.BrainFlowZZZMetadataWizardpage45Panel);
            app.StartingTimeRateEditFieldLabel.Text = 'Starting Time Rate';
            app.LocationEditFieldLabel = uilabel(app.BrainFlowZZZMetadataWizardpage45Panel);
            app.LocationEditFieldLabel.Text = 'Location';
            app.NoteLabel = uilabel(app.BrainFlowZZZMetadataWizardpage45Panel);
            app.NoteLabel.Text = 'Note: select the channels tabs below in order as needed. eg., if only two channels are present, fill Chan1 and Chan2.';
            app.appDataTextAreaLabel = uilabel(app.BrainFlowZZZMetadataWizardpage45Panel);
            app.appDataTextAreaLabel.Enable = 'off';
            app.appDataTextAreaLabel.Visible = 'off';
            app.appDataTextAreaLabel.Text = 'appData';
            app.appDataTextArea = uitextarea(app.BrainFlowZZZMetadataWizardpage45Panel);
            app.appDataTextArea.Editable = 'off';
            app.appDataTextArea.Enable = 'off';
            app.appDataTextArea.Visible = 'off';
            app.GridSpacingUnitEditFieldLabel = uilabel(app.BrainFlowZZZMetadataWizardpage45Panel);
            app.GridSpacingUnitEditFieldLabel.Text = 'Grid Spacing Unit';
            app.BrainFlowZZZMetadataWizardpage35Panel = uipanel(app.UIFigure);
            app.BrainFlowZZZMetadataWizardpage35Panel.Title = 'BrainFlowZZZ Metadata Wizard (page 3/5)';
            app.SessionIDEditField = uieditfield(app.BrainFlowZZZMetadataWizardpage35Panel, 'text');
            app.SessionIDEditField.Placeholder = 'eg., 20210511-m1';
            app.IdentifierEditField = uieditfield(app.BrainFlowZZZMetadataWizardpage35Panel, 'text');
            app.IdentifierEditField.Placeholder = 'can use the folder name';
            app.StartTimeEditField = uieditfield(app.BrainFlowZZZMetadataWizardpage35Panel, 'text');
            app.StartTimeEditField.ValueChangedFcn = createCallbackFcn(app, @StartTimeEditFieldValueChanged, true);
            app.StartTimeEditField.Placeholder = '(yyyy-MM-dd HH:mm:ss)';
            app.KeywordsTextArea = uitextarea(app.BrainFlowZZZMetadataWizardpage35Panel);
            app.KeywordsTextArea.Placeholder = 'eg., BPN Zstack, B-act Zstack';
            app.SessionDescriptionTextArea = uitextarea(app.BrainFlowZZZMetadataWizardpage35Panel);
            app.NextButton_3 = uibutton(app.BrainFlowZZZMetadataWizardpage35Panel, 'push');
            app.NextButton_3.ButtonPushedFcn = createCallbackFcn(app, @NextButton_3Pushed, true);
            app.NextButton_3.Text = 'Next';
            app.BackButton_3 = uibutton(app.BrainFlowZZZMetadataWizardpage35Panel, 'push');
            app.BackButton_3.ButtonPushedFcn = createCallbackFcn(app, @BackButton_3Pushed, true);
            app.BackButton_3.Text = 'Back';
            app.SessionInfoLabel = uilabel(app.BrainFlowZZZMetadataWizardpage35Panel);
            app.SessionInfoLabel.Text = 'Session Info';
            app.ErrorMessageLabel = uilabel(app.BrainFlowZZZMetadataWizardpage35Panel);
            app.ErrorMessageLabel.Text = '';
            app.EditField = uieditfield(app.BrainFlowZZZMetadataWizardpage35Panel, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Enable = 'off';
            app.EditField.Visible = 'off';
            app.SessionDescriptionLabel = uilabel(app.BrainFlowZZZMetadataWizardpage35Panel);
            app.SessionDescriptionLabel.Text = 'Session Description';
            app.KeywordsTextAreaLabel = uilabel(app.BrainFlowZZZMetadataWizardpage35Panel);
            app.KeywordsTextAreaLabel.Text = 'Keywords';
            app.StartTimeEditFieldLabel = uilabel(app.BrainFlowZZZMetadataWizardpage35Panel);
            app.StartTimeEditFieldLabel.Text = 'Start Time';
            app.IdentifierEditFieldLabel = uilabel(app.BrainFlowZZZMetadataWizardpage35Panel);
            app.IdentifierEditFieldLabel.Text = 'Identifier';
            app.SessionIDEditFieldLabel = uilabel(app.BrainFlowZZZMetadataWizardpage35Panel);
            app.SessionIDEditFieldLabel.Text = 'Session ID';
            app.BrainFlowZZZMetadataWizardpage25Panel = uipanel(app.UIFigure);
            app.BrainFlowZZZMetadataWizardpage25Panel.Title = 'BrainFlowZZZ Metadata Wizard (page 2/5)';
            app.NameEditField = uieditfield(app.BrainFlowZZZMetadataWizardpage25Panel, 'text');
            app.NameEditField.Placeholder = 'Last Name, First Name';
            app.NameEditFieldLabel = uilabel(app.BrainFlowZZZMetadataWizardpage25Panel);
            app.NameEditFieldLabel.Text = 'Name';
            app.InstitutionEditField = uieditfield(app.BrainFlowZZZMetadataWizardpage25Panel, 'text');
            app.MicroscopeEditField = uieditfield(app.BrainFlowZZZMetadataWizardpage25Panel, 'text');
            app.MicroscopeEditField.Tooltip = {'microscope model'};
            app.MicroscopeEditField.Placeholder = 'eg., Bergamo II';
            app.ManufacturerEditField = uieditfield(app.BrainFlowZZZMetadataWizardpage25Panel, 'text');
            app.ManufacturerEditField.Placeholder = 'eg., ThorLabs';
            app.ExperimentDescriptionTextArea = uitextarea(app.BrainFlowZZZMetadataWizardpage25Panel);
            app.InstitutionEditField_2Label = uilabel(app.BrainFlowZZZMetadataWizardpage25Panel);
            app.InstitutionEditField_2Label.Text = 'Institution';
            app.NextButton_2 = uibutton(app.BrainFlowZZZMetadataWizardpage25Panel, 'push');
            app.NextButton_2.ButtonPushedFcn = createCallbackFcn(app, @NextButton_2Pushed, true);
            app.NextButton_2.Text = 'Next';
            app.BackButton_2 = uibutton(app.BrainFlowZZZMetadataWizardpage25Panel, 'push');
            app.BackButton_2.ButtonPushedFcn = createCallbackFcn(app, @BackButton_2Pushed, true);
            app.BackButton_2.Text = 'Back';
            app.ExperimenterInfoLabel = uilabel(app.BrainFlowZZZMetadataWizardpage25Panel);
            app.ExperimenterInfoLabel.Text = 'Experimenter Info';
            app.ErrorMessageLabel_2 = uilabel(app.BrainFlowZZZMetadataWizardpage25Panel);
            app.ErrorMessageLabel_2.Text = '';
            app.EditField_2 = uieditfield(app.BrainFlowZZZMetadataWizardpage25Panel, 'text');
            app.EditField_2.Editable = 'off';
            app.EditField_2.Enable = 'off';
            app.EditField_2.Visible = 'off';
            app.ExperimentDescriptionTextArea_2Label = uilabel(app.BrainFlowZZZMetadataWizardpage25Panel);
            app.ExperimentDescriptionTextArea_2Label.Text = 'Experiment Description';
            app.ManufacturerEditFieldLabel = uilabel(app.BrainFlowZZZMetadataWizardpage25Panel);
            app.ManufacturerEditFieldLabel.Text = 'Manufacturer';
            app.MicroscopeEditFieldLabel = uilabel(app.BrainFlowZZZMetadataWizardpage25Panel);
            app.MicroscopeEditFieldLabel.Text = 'Microscope';
            app.ExperimentInfoLabel = uilabel(app.BrainFlowZZZMetadataWizardpage25Panel);
            app.ExperimentInfoLabel.Text = 'Experiment Info';
            app.BrainFlowZZZMetadataWizardpage15Panel = uipanel(app.UIFigure);
            app.BrainFlowZZZMetadataWizardpage15Panel.Title = 'BrainFlowZZZ Metadata Wizard (page 1/5)';
            app.selectdirectoryButton = uibutton(app.BrainFlowZZZMetadataWizardpage15Panel, 'push');
            app.selectdirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @selectdirectoryButtonPushed, true);
            app.selectdirectoryButton.Text = 'select directory';
            app.SelectDirectoryTextArea = uitextarea(app.BrainFlowZZZMetadataWizardpage15Panel);
            app.SelectDirectoryTextArea.ValueChangedFcn = createCallbackFcn(app, @selectdirectoryButtonPushed, true);
            app.SelectDirectoryTextArea.Editable = 'off';
            app.NextButton_1 = uibutton(app.BrainFlowZZZMetadataWizardpage15Panel, 'push');
            app.NextButton_1.ButtonPushedFcn = createCallbackFcn(app, @NextButton_1Pushed, true);
            app.NextButton_1.Text = 'Next';
            app.BrainFlowZZZMetadataWizardLabel = uilabel(app.BrainFlowZZZMetadataWizardpage15Panel);
            app.BrainFlowZZZMetadataWizardLabel.Text = 'BrainFlowZZZ Metadata Wizard';
            app.HomePageLabel = uilabel(app.BrainFlowZZZMetadataWizardpage15Panel);
            app.HomePageLabel.Text = {'Welcome to BrainFlowZZZ Metadata Wizard! This tool guides you to enter the experiment session info according the the NWB specifications. '; ''; 'First, please select the directory to write your metadata.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = metadata_wizard

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end